classdef SimpleCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PleaseenteryourradianvalueintheformxpiyLabel  matlab.ui.control.Label
        AprojectbyAryanPatilandNicholasSparagesLabel  matlab.ui.control.Label
        SupportsAngularandRadianValuesLabel  matlab.ui.control.Label
        EnterValueofXEditField       matlab.ui.control.EditField
        EnterValueofXEditFieldLabel  matlab.ui.control.Label
        ResultEditField              matlab.ui.control.NumericEditField
        ResultEditFieldLabel         matlab.ui.control.Label
        CotXButton                   matlab.ui.control.Button
        CscXButton                   matlab.ui.control.Button
        SecXButton                   matlab.ui.control.Button
        TanXButton                   matlab.ui.control.Button
        CosXButton                   matlab.ui.control.Button
        SinXButton                   matlab.ui.control.Button
        TrignometricCalculatorLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SinXButton
        function SinXButtonPushed(app, event)
            numValue = str2double(app.EnterValueofXEditField.Value);
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    app.ResultEditField.Value = sinpi(Numerator/Denominator);           
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = sinpi(Numerator/Denominator);
                
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    app.ResultEditField.Value = sinpi(Numerator/Denominator);

                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = sinpi(Numerator/Denominator);
                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = sind(numValue);
            end
        end

        % Button pushed function: CosXButton
        function CosXButtonPushed(app, event)
            numValue = str2double(app.EnterValueofXEditField.Value);
            
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    app.ResultEditField.Value = cospi(Numerator/Denominator);           
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = cospi(Numerator/Denominator);
                
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    app.ResultEditField.Value = cospi(Numerator/Denominator);

                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = cospi(Numerator/Denominator);
                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = cosd(numValue);
            end
        end

        % Button pushed function: TanXButton
        function TanXButtonPushed(app, event)
            numValue = str2double(app.EnterValueofXEditField.Value);
            
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = SinValue/CosValue;          
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = SinValue/CosValue;          
 
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = SinValue/CosValue;          


                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = SinValue/CosValue;          

                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = tand(numValue);
            end

        end

        % Button pushed function: CscXButton
        function CscXButtonPushed(app, event)
            numValue = str2double(app.EnterValueofXEditField.Value);
            
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    app.ResultEditField.Value = 1/(sinpi(Numerator/Denominator));           
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = 1/(sinpi(Numerator/Denominator));
                
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    app.ResultEditField.Value = 1/(sinpi(Numerator/Denominator));

                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = 1/(sinpi(Numerator/Denominator));
                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = 1/(sind(numValue));
            end            
        end

        % Button pushed function: SecXButton
        function SecXButtonPushed(app, event)
            numValue = str2double(app.EnterValueofXEditField.Value);
            
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    app.ResultEditField.Value = 1/(cospi(Numerator/Denominator));           
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = 1/(cospi(Numerator/Denominator));
                
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    app.ResultEditField.Value = 1/(cospi(Numerator/Denominator));

                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    app.ResultEditField.Value = 1/(cospi(Numerator/Denominator));
                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = secd(numValue);
            end            
        end

        % Button pushed function: CotXButton
        function CotXButtonPushed(app, event)
                        numValue = str2double(app.EnterValueofXEditField.Value);
            
            % If the string contains "pi" in it, assume it is in radians
            if (contains(app.EnterValueofXEditField.Value,"pi"))

                % Extract the Coefficients from the radian function
                StrNumerator = extractBefore(app.EnterValueofXEditField.Value,"pi");            
                StrDenominator = extractAfter(app.EnterValueofXEditField.Value,"/");

                % If there are no coefficients to the fuction (example, "pi"), replace them with 1 and find the value.
                if (StrNumerator == "") && (StrDenominator == "")
                    Numerator = 1;
                    Denominator = 1;
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = 1/(SinValue/CosValue);          
               
                % If there isnt a coefficient in the numerator, replace with 1 and find the value.    
                elseif (StrNumerator == "")
                    Numerator = 1;
                    Denominator = str2double(StrDenominator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = 1/(SinValue/CosValue);          
 
                % If there isnt a coefficient in the denominator, replace with 1 and find the value.
                elseif (StrDenominator == "")
                    Denominator = 1;
                    Numerator = str2double(StrNumerator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = 1/(SinValue/CosValue);          


                % If both coefficients exist, convert them to numbers and find the value.    
                else  
                    Numerator = str2double(StrNumerator);
                    Denominator = str2double(StrDenominator);
                    SinValue = sinpi(Numerator/Denominator);
                    CosValue = cospi(Numerator/Denominator);
                    app.ResultEditField.Value = 1/(SinValue/CosValue);          

                end
            else
                % If the value exists in degree form. (waaay easier to do)
                app.ResultEditField.Value = cotd(numValue);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrignometricCalculatorLabel = uilabel(app.UIFigure);
            app.TrignometricCalculatorLabel.Text = 'Trignometric Calculator';
            app.SinXButton = uibutton(app.UIFigure, 'push');
            app.SinXButton.ButtonPushedFcn = createCallbackFcn(app, @SinXButtonPushed, true);
            app.SinXButton.Text = 'Sin(X)';
            app.CosXButton = uibutton(app.UIFigure, 'push');
            app.CosXButton.ButtonPushedFcn = createCallbackFcn(app, @CosXButtonPushed, true);
            app.CosXButton.Text = 'Cos(X)';
            app.TanXButton = uibutton(app.UIFigure, 'push');
            app.TanXButton.ButtonPushedFcn = createCallbackFcn(app, @TanXButtonPushed, true);
            app.TanXButton.Text = 'Tan(X)';
            app.SecXButton = uibutton(app.UIFigure, 'push');
            app.SecXButton.ButtonPushedFcn = createCallbackFcn(app, @SecXButtonPushed, true);
            app.SecXButton.Text = 'Sec(X)';
            app.CscXButton = uibutton(app.UIFigure, 'push');
            app.CscXButton.ButtonPushedFcn = createCallbackFcn(app, @CscXButtonPushed, true);
            app.CscXButton.Text = 'Csc(X)';
            app.CotXButton = uibutton(app.UIFigure, 'push');
            app.CotXButton.ButtonPushedFcn = createCallbackFcn(app, @CotXButtonPushed, true);
            app.CotXButton.Text = 'Cot(X)';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterValueofXEditFieldLabel = uilabel(app.UIFigure);
            app.EnterValueofXEditFieldLabel.Text = 'Enter Value of X';
            app.EnterValueofXEditField = uieditfield(app.UIFigure, 'text');
            app.SupportsAngularandRadianValuesLabel = uilabel(app.UIFigure);
            app.SupportsAngularandRadianValuesLabel.Text = 'Supports Angular and Radian Values';
            app.AprojectbyAryanPatilandNicholasSparagesLabel = uilabel(app.UIFigure);
            app.AprojectbyAryanPatilandNicholasSparagesLabel.Text = 'A project by Aryan Patil and Nicholas Sparages';
            app.PleaseenteryourradianvalueintheformxpiyLabel = uilabel(app.UIFigure);
            app.PleaseenteryourradianvalueintheformxpiyLabel.Text = 'Please enter your radian value in the form xpi/y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end